classdef camera_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        HelpMenu                 matlab.ui.container.Menu
        BrightnessSlider         matlab.ui.control.Slider
        BrightnessSliderLabel    matlab.ui.control.Label
        RestoredefaultButton     matlab.ui.control.Button
        CancelButton             matlab.ui.control.Button
        ProceedButton            matlab.ui.control.Button
        PreviewButton            matlab.ui.control.Button
        ResolutionDropDown       matlab.ui.control.DropDown
        ResolutionDropDownLabel  matlab.ui.control.Label
        SharpnessSlider          matlab.ui.control.Slider
        SharpnessSliderLabel     matlab.ui.control.Label
        SaturationSlider         matlab.ui.control.Slider
        SaturationSliderLabel    matlab.ui.control.Label
        ContrastSlider           matlab.ui.control.Slider
        ContrastSliderLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        main_interface
        camera% Description
        help_interface
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, cam)
            app.main_interface = callingApp;
            app.camera = cam;
            app.ResolutionDropDown.Value = app.camera.resolution;
            app.BrightnessSlider.Value = app.camera.brightness;
            app.ContrastSlider.Value = app.camera.contrast;
            app.SaturationSlider.Value = app.camera.saturation;
            app.SharpnessSlider.Value = app.camera.sharpness;
        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)
            try
                overWirteCam(app.main_interface, app.ResolutionDropDown.Value, round(app.BrightnessSlider.Value), round(app.ContrastSlider.Value), round(app.SaturationSlider.Value), round(app.SharpnessSlider.Value))
                enableButtons(app.main_interface);
                delete(app)
            catch
                detele(app)
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            try
                enableButtons(app.main_interface);
                delete(app)
            catch
                detele(app)
            end
        end

        % Button pushed function: RestoredefaultButton
        function RestoredefaultButtonPushed(app, event)
            app.ResolutionDropDown.Value = app.camera.getDefRes();
            app.BrightnessSlider.Value = app.camera.getDefBri();
            app.ContrastSlider.Value = app.camera.getDefCont();
            app.SaturationSlider.Value = app.camera.getDefSat();
            app.SharpnessSlider.Value = app.camera.getDefShar();
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            %%add code later
            fig = figure
            imshow(app.camera.tempImage(round(app.BrightnessSlider.Value),app.ResolutionDropDown.Value, round(app.ContrastSlider.Value),round(app.SaturationSlider.Value),round(app.SharpnessSlider.Value)))
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                enableButtons(app.main_interface);
                delete(app)
            catch
                delete(app)
            end
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            app.help_interface = camera_help_interface;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.ContrastSliderLabel = uilabel(app.UIFigure);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.UIFigure);
            app.ContrastSlider.Limits = [0 8];
            app.ContrastSlider.MinorTicks = [];
            app.SaturationSliderLabel = uilabel(app.UIFigure);
            app.SaturationSliderLabel.Text = 'Saturation';
            app.SaturationSlider = uislider(app.UIFigure);
            app.SaturationSlider.Limits = [0 8];
            app.SaturationSlider.MinorTicks = [];
            app.SharpnessSliderLabel = uilabel(app.UIFigure);
            app.SharpnessSliderLabel.Text = 'Sharpness';
            app.SharpnessSlider = uislider(app.UIFigure);
            app.SharpnessSlider.Limits = [0 8];
            app.SharpnessSlider.MinorTicks = [];
            app.ResolutionDropDownLabel = uilabel(app.UIFigure);
            app.ResolutionDropDownLabel.Text = 'Resolution';
            app.ResolutionDropDown = uidropdown(app.UIFigure);
            app.ResolutionDropDown.Items = {'4416x1242', '3840x1080', '2560x720', '1344x376'};
            app.ResolutionDropDown.Value = '2560x720';
            app.PreviewButton = uibutton(app.UIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.ProceedButton = uibutton(app.UIFigure, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Text = 'Proceed';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.RestoredefaultButton = uibutton(app.UIFigure, 'push');
            app.RestoredefaultButton.ButtonPushedFcn = createCallbackFcn(app, @RestoredefaultButtonPushed, true);
            app.RestoredefaultButton.Text = 'Restore default';
            app.BrightnessSliderLabel = uilabel(app.UIFigure);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.BrightnessSlider = uislider(app.UIFigure);
            app.BrightnessSlider.Limits = [0 8];
            app.BrightnessSlider.MinorTicks = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = camera_interface(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end