classdef diameter_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Label                 matlab.ui.control.Label
        RestoredefaultButton  matlab.ui.control.Button
        ProceedButton         matlab.ui.control.Button
        CancelButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        main_interface
        newDia% Description
        capDet
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, capDetector, newDia)
            app.main_interface = callingApp;
            app.newDia = newDia;
            app.capDet = capDetector;
            txt = "You are changing the cap detection diameter from " + num2str(app.capDet.diameter)+  " to "  + num2str(newDia) + ".";
            txt = txt + newline + "Would you like to proceed?";
            app.Label.Text = txt;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            enableButtons(app.main_interface);
            delete(app)
        end

        % Button pushed function: RestoredefaultButton
        function RestoredefaultButtonPushed(app, event)
            %%set to default
            overwriteDia(app.main_interface, app.capDet.defDia);
            enableButtons(app.main_interface);
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            enableButtons(app.main_interface);
            delete(app)
        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)
            %%update to newDia
            overwriteDia(app.main_interface, app.newDia);
            enableButtons(app.main_interface);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ProceedButton = uibutton(app.UIFigure, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Text = 'Proceed';
            app.RestoredefaultButton = uibutton(app.UIFigure, 'push');
            app.RestoredefaultButton.ButtonPushedFcn = createCallbackFcn(app, @RestoredefaultButtonPushed, true);
            app.RestoredefaultButton.Text = 'Restore default';
            app.Label = uilabel(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = diameter_interface(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end