classdef main_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AnalysisResult                  matlab.ui.Figure
        SettingMenu                     matlab.ui.container.Menu
        SetRGBboundariesMenu            matlab.ui.container.Menu
        SetcapdetectionsensitivityMenu  matlab.ui.container.Menu
        SetcapsdiameterMenu             matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        sampleImage                     matlab.ui.control.Image
        stateLabel                      matlab.ui.control.Label
        RunpalletizingsequenceButton    matlab.ui.control.Button
        OthertasksButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        RBGApp
        senseApp% Description
        helpApp
        otherApp
        diamterApp

        capDetector
        tcpConnection
    end
       
    methods (Access = public)
        
        function results = enableButtons(app)
            if app.RunpalletizingsequenceButton.Enable == false
                app.RunpalletizingsequenceButton.Enable = true;
                app.OthertasksButton.Enable = true;
                app.SettingMenu.Enable = true;
                results = true;
                return
            else
                results = false;
                return
            end 
        end

        function result = overwriteSense(app, capDet)
            app.capDetector = app.capDetector.setVariables(capDet.sensitivity, capDet.edgethresh);
            app.capDetector
            result = true;
            return
        end
        function result = overwriteDia(app, newDia)
            app.capDetector = app.capDetector.setDia(newDia);
            app.capDetector
            result = true;
            return
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.capDetector = cap_detection(0.9, 0.05, 65);
% fig = openfig("Success.fig", "invisible");
%             saveas(fig,"sampleFig.png")
% %             a = findobj(fig, 'Type',"Image");
% %             copyobj(a.Children, app.AnalysisResult);
% %             delete(fig)
%             im = imread('samplefig.png');
%             app.sampleImage.ImageSource = im;
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            app.helpApp = help_interface;
        end

        % Menu selected function: SetRGBboundariesMenu
        function SetRGBboundariesMenuSelected(app, event)
            app.RunpalletizingsequenceButton.Enable = false;
            app.OthertasksButton.Enable = false;
            app.SettingMenu.Enable = false;
            %% make sure to add colour detecter object as an input arg
            app.RBGApp = RGB_interface(app);
        end

        % Menu selected function: SetcapdetectionsensitivityMenu
        function SetcapdetectionsensitivityMenuSelected(app, event)
            app.RunpalletizingsequenceButton.Enable = false;
            app.OthertasksButton.Enable = false;
            app.SettingMenu.Enable = false;
            %% make sure to add circle detecter object as an input arg
            app.senseApp = sense_interface(app, app.capDetector);
        end

        % Button pushed function: OthertasksButton
        function OthertasksButtonPushed(app, event)
            app.RunpalletizingsequenceButton.Enable = false;
            app.OthertasksButton.Enable = false;
            app.SettingMenu.Enable = false;
            app.otherApp = other_interface(app);
        end

        % Menu selected function: SetcapsdiameterMenu
        function SetcapsdiameterMenuSelected(app, event)
            %%after that
            app.RunpalletizingsequenceButton.Enable = false;
            app.OthertasksButton.Enable = false;
            app.SettingMenu.Enable = false;
            %%take photo and make them draw line to find new diameter
            dia = app.capDetector.obtainDiameter(imread("sampleFig.png"));
            app.diamterApp = diameter_interface(app,app.capDetector,dia);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AnalysisResult = uifigure('Visible', 'off');
            app.AnalysisResult.Name = 'MATLAB App';
            app.SettingMenu = uimenu(app.AnalysisResult);
            app.SettingMenu.Text = 'Setting';
            app.SetRGBboundariesMenu = uimenu(app.SettingMenu);
            app.SetRGBboundariesMenu.MenuSelectedFcn = createCallbackFcn(app, @SetRGBboundariesMenuSelected, true);
            app.SetRGBboundariesMenu.Text = 'Set RGB boundaries';
            app.SetcapdetectionsensitivityMenu = uimenu(app.SettingMenu);
            app.SetcapdetectionsensitivityMenu.MenuSelectedFcn = createCallbackFcn(app, @SetcapdetectionsensitivityMenuSelected, true);
            app.SetcapdetectionsensitivityMenu.Text = 'Set cap detection sensitivity';
            app.SetcapsdiameterMenu = uimenu(app.SettingMenu);
            app.SetcapsdiameterMenu.MenuSelectedFcn = createCallbackFcn(app, @SetcapsdiameterMenuSelected, true);
            app.SetcapsdiameterMenu.Text = 'Set caps diameter';
            app.HelpMenu = uimenu(app.AnalysisResult);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.OthertasksButton = uibutton(app.AnalysisResult, 'push');
            app.OthertasksButton.ButtonPushedFcn = createCallbackFcn(app, @OthertasksButtonPushed, true);
            app.OthertasksButton.Text = 'Other tasks';
            app.RunpalletizingsequenceButton = uibutton(app.AnalysisResult, 'push');
            app.RunpalletizingsequenceButton.Text = 'Run palletizing sequence';
            app.stateLabel = uilabel(app.AnalysisResult);
            app.stateLabel.Text = '';
            app.sampleImage = uiimage(app.AnalysisResult);
            app.sampleImage.ImageSource = 'welcometo.png';
            % Show the figure after all components are created
            app.AnalysisResult.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AnalysisResult)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AnalysisResult)
        end
    end
end