classdef other_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TESTERButton           matlab.ui.control.Button
        CloseButton            matlab.ui.control.Button
        takephotoofyouButton   matlab.ui.control.Button
        DrawAUTonboardButton   matlab.ui.control.Button
        TakesampleimageButton  matlab.ui.control.Button
        explanationLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        main_interface % Description
        camera
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, cam)
            txt = "In this window, we have excitig tasks using the robot arm";
            txt = txt + newline + "otehr than simulating a palletizing process";
            app.explanationLabel.Text = txt;
            app.main_interface = callingApp;
            app.camera = cam;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            enableButtons(app.main_interface)
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            enableButtons(app.main_interface)
            delete(app)
        end

        % Button pushed function: TakesampleimageButton
        function TakesampleimageButtonPushed(app, event)
            fig = figure;
            imshow(app.camera.manualImageAcq());
        end

        % Button pushed function: TESTERButton
        function TESTERButtonPushed(app, event)
            %%WRITE CODE FOR TESTING!!
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.explanationLabel = uilabel(app.UIFigure);
            app.explanationLabel.Text = '';
            app.TakesampleimageButton = uibutton(app.UIFigure, 'push');
            app.TakesampleimageButton.ButtonPushedFcn = createCallbackFcn(app, @TakesampleimageButtonPushed, true);
            app.TakesampleimageButton.Text = 'Take sample image';
            app.DrawAUTonboardButton = uibutton(app.UIFigure, 'push');
            app.DrawAUTonboardButton.Text = 'Draw AUT on board';
            app.takephotoofyouButton = uibutton(app.UIFigure, 'push');
            app.takephotoofyouButton.Text = 'take photo of you';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.TESTERButton = uibutton(app.UIFigure, 'push');
            app.TESTERButton.ButtonPushedFcn = createCallbackFcn(app, @TESTERButtonPushed, true);
            app.TESTERButton.Text = 'TESTER';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = other_interface(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end