classdef sense_help_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Label        matlab.ui.control.Label
        CloseButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            txt = "There are two parameters to consider when trying to detect caps: Sensitivity, and Edge threshold. ";
            txt = txt + newline + newline + " -Sensitivity is in charge of shape of objects (how circular the objects are) ranging from 0 to 1.";
            txt = txt + newline + "  Setting sensitivity high will allow more deformed circles to be detected as a cap ";
            txt = txt + newline + "  [recommended values are ranging from 0.9 to 0.93]";
            txt = txt + newline + newline + " -Edge threshold deals with the edges of the objects";
            txt = txt + newline + "  (how strong the edge of objects are) ranging from 0 to 1.";
            txt = txt + newline + "  Setting edge threshold low will allow weaker edged objects to be detected as a cap";
            txt = txt + newline + "  [recommended values are ranging from 0.01 to 0.05]";
            
            app.Label.Text = txt;
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sense_help_interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end