classdef sense_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        HelpMenu                  matlab.ui.container.Menu
        ApplychangeandexitButton  matlab.ui.control.Button
        CancelButton              matlab.ui.control.Button
        RestoredefaultButton      matlab.ui.control.Button
        SensitivitySliderLabel    matlab.ui.control.Label
        SensitivitySlider         matlab.ui.control.Slider
        EdgeThresholdSliderLabel  matlab.ui.control.Label
        EdgeThresholdSlider       matlab.ui.control.Slider
    end

    
    properties (Access = private)
        main_interface % Description
        circleDet

        help_interface
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, callingApp, circleDetector)
            %% make sure to add circle detecter object as an input arg
            app.main_interface = callingApp;
            app.circleDet = circleDetector;
            app.EdgeThresholdSlider.Value = app.circleDet.edgethresh;
            app.SensitivitySlider.Value = app.circleDet.sensitivity;
        end

        % Button pushed function: ApplychangeandexitButton
        function ApplychangeandexitButtonPushed(app, event)
            %update values
            app.circleDet = app.circleDet.setVariables(app.SensitivitySlider.Value, app.EdgeThresholdSlider.Value);
            overwriteSense(app.main_interface, app.circleDet);
            enableButtons(app.main_interface);
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            enableButtons(app.main_interface)
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            enableButtons(app.main_interface)
            delete(app)
        end

        % Button pushed function: RestoredefaultButton
        function RestoredefaultButtonPushed(app, event)
            %% add callback
            app.EdgeThresholdSlider.Value = app.circleDet.defThre;
            app.SensitivitySlider.Value = app.circleDet.defSens;
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            app.help_interface = sense_help_interface;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help';
            app.ApplychangeandexitButton = uibutton(app.UIFigure, 'push');
            app.ApplychangeandexitButton.ButtonPushedFcn = createCallbackFcn(app, @ApplychangeandexitButtonPushed, true);
            app.ApplychangeandexitButton.Text = 'Apply change and exit';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.RestoredefaultButton = uibutton(app.UIFigure, 'push');
            app.RestoredefaultButton.ButtonPushedFcn = createCallbackFcn(app, @RestoredefaultButtonPushed, true);
            app.RestoredefaultButton.Text = 'Restore default';
            app.SensitivitySliderLabel = uilabel(app.UIFigure);
            app.SensitivitySliderLabel.Text = 'Sensitivity';
            app.SensitivitySlider = uislider(app.UIFigure);
            app.SensitivitySlider.Limits = [0.9 1];
            app.SensitivitySlider.Value = 0.9;
            app.EdgeThresholdSliderLabel = uilabel(app.UIFigure);
            app.EdgeThresholdSliderLabel.Text = 'Edge Threshold';
            app.EdgeThresholdSlider = uislider(app.UIFigure);
            app.EdgeThresholdSlider.Limits = [0 0.05];
            app.EdgeThresholdSlider.Value = 0.05;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sense_interface(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end